classdef DialogParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreprocessingparametersPanel    matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        DatasetLabel                    matlab.ui.control.Label
        HzBandpassfilterLabel           matlab.ui.control.Label
        EditFieldLF                     matlab.ui.control.NumericEditField
        EditFieldHF                     matlab.ui.control.NumericEditField
        DownsamplingCheckBox            matlab.ui.control.CheckBox
        CommonAverageReferenceCheckBox  matlab.ui.control.CheckBox
        Button                          matlab.ui.control.Button
    end


    properties (Access = private) 
        CallingApp   % Main app object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, errpapp, datasetname, car, lf, hf, downsamp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = errpapp;
            
            % Update UI with input values
            app.Label.Text = datasetname;
            app.CommonAverageReferenceCheckBox.Value = car;
            app.EditFieldLF.Value = lf;
            app.EditFieldHF.Value = hf;
            app.DownsamplingCheckBox.Value = downsamp;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function
            updateparams(app.CallingApp,  app.CommonAverageReferenceCheckBox.Value, app.EditFieldLF.Value, app.EditFieldHF.Value, app.DownsamplingCheckBox.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.PreprocessingparametersPanel = uipanel(app.UIFigure);
            app.PreprocessingparametersPanel.Title = 'Pre-processing parameters';
            app.Button = uibutton(app.PreprocessingparametersPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'OK';
            app.CommonAverageReferenceCheckBox = uicheckbox(app.PreprocessingparametersPanel);
            app.CommonAverageReferenceCheckBox.Text = 'Common Average Reference';
            app.DownsamplingCheckBox = uicheckbox(app.PreprocessingparametersPanel);
            app.DownsamplingCheckBox.Text = 'Downsampling';
            app.EditFieldHF = uieditfield(app.PreprocessingparametersPanel, 'numeric');
            app.EditFieldHF.Limits = [0 1000];
            app.EditFieldHF.Value = 10;
            app.EditFieldLF = uieditfield(app.PreprocessingparametersPanel, 'numeric');
            app.EditFieldLF.Limits = [0 1000];
            app.EditFieldLF.Value = 1;
            app.HzBandpassfilterLabel = uilabel(app.PreprocessingparametersPanel);
            app.HzBandpassfilterLabel.Text = 'Hz Bandpass filter';
            app.DatasetLabel = uilabel(app.PreprocessingparametersPanel);
            app.DatasetLabel.Text = 'Dataset:';
            app.Label = uilabel(app.PreprocessingparametersPanel);
            app.Label.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogParams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end