classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ErrPDetectionPipelinesUIFigure  matlab.ui.Figure
        AutoregressiveCheckBox          matlab.ui.control.CheckBox
        PCACheckBox                     matlab.ui.control.CheckBox
        WindowSelectionCheckBox         matlab.ui.control.CheckBox
        RsquareCheckBox                 matlab.ui.control.CheckBox
        AugmentationCheckBox            matlab.ui.control.CheckBox
        SVMrbfCheckBox                  matlab.ui.control.CheckBox
        SVMCheckBox                     matlab.ui.control.CheckBox
        sLDACheckBox                    matlab.ui.control.CheckBox
        LDACheckBox                     matlab.ui.control.CheckBox
        GNBCheckBox                     matlab.ui.control.CheckBox
        BayesCheckBox                   matlab.ui.control.CheckBox
        TimePointsCheckBox              matlab.ui.control.CheckBox
        PeakAvgCheckBox                 matlab.ui.control.CheckBox
        FczCzCheckBox                   matlab.ui.control.CheckBox
        ConsecAvgCheckBox               matlab.ui.control.CheckBox
        OverlapAvgCheckBox              matlab.ui.control.CheckBox
        xDAWNCheckBox                   matlab.ui.control.CheckBox
        FCBCheckBox                     matlab.ui.control.CheckBox
        DownsamplingCheckBox            matlab.ui.control.CheckBox
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetDropDownLabel            matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        ErrPApp; % Description
        datasetname;
        downsampling=0;
        winselect=0;
        featset=1:9;
        features = [0 0 0 0 0 0 1 0 0];
        nofeats = 200; % [50 100 150 200 250];
        normalization=1;
        featselect=0;
        featselectmethod=0;
        augmentation=0;
        clsfrset=1:6;
        classifiers = [1 1 1 1 1 1];
        currentfolder;
    end
    
    methods (Access = public)
        
        function initializePipelineParams(app)
            app.currentfolder = pwd;
            app.DownsamplingCheckBox.Value = app.downsampling;
            app.WindowSelectionCheckBox.Value = app.winselect;
            app.FCBCheckBox.Value = app.features(1);
            app.xDAWNCheckBox.Value = app.features(2);
            app.OverlapAvgCheckBox.Value = app.features(3);
            app.ConsecAvgCheckBox.Value = app.features(4);
            app.FczCzCheckBox.Value = app.features(5);
            app.PCACheckBox.Value = (app.featselect==1 && app.featselectmethod==2);
            app.TimePointsCheckBox.Value = app.features(7);
            app.PeakAvgCheckBox.Value = app.features(8);
            app.AutoregressiveCheckBox.Value = app.features(9);
            app.RsquareCheckBox.Value = (app.featselect==1 && app.featselectmethod==1);
            app.AugmentationCheckBox.Value = app.augmentation;
            app.BayesCheckBox.Value = app.classifiers(1);
            app.GNBCheckBox.Value = app.classifiers(2);
            app.LDACheckBox.Value = app.classifiers(3);
            app.sLDACheckBox.Value = app.classifiers(4);
            app.SVMCheckBox.Value = app.classifiers(5);
            app.SVMrbfCheckBox.Value = app.classifiers(6);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializePipelineParams(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Disable Run button while ErrP app is open
            app.RunButton.Enable = 'off';
            DatasetDropDownValueChanged(app);
            fprintf("***********************\n");
            fprintf("Dataset (%s) classifying for feature sets... \n",app.datasetname);
            fprintf("***********************\n");
             for i=1:length(app.nofeats)
                  for feat=app.featset(app.features==1) 
                % Open the ErrP app and pass inputs
                app.ErrPApp = ErrP_APP(app, app.datasetname, app.currentfolder, app.downsampling, app.winselect, feat, app.normalization, app.featselectmethod, app.nofeats(i), app.augmentation, app.classifiers);
                 % Delete the app
                %delete(app.ErrPApp)
                 fprintf("***********************\n");
                fprintf("Feature set %d completed! \n",feat);
                fprintf("  \n");
                   end
            end
            app.RunButton.Enable = 'on';
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            app.datasetname = app.DatasetDropDown.Value;
            
        end

        % Value changed function: FCBCheckBox
        function FCBCheckBoxValueChanged(app, event)
             app.features(1) = app.FCBCheckBox.Value;
        end

        % Value changed function: DownsamplingCheckBox
        function DownsamplingCheckBoxValueChanged(app, event)
            app.downsampling = app.DownsamplingCheckBox.Value;
            
        end

        % Value changed function: WindowSelectionCheckBox
        function WindowSelectionCheckBoxValueChanged(app, event)
            app.winselect = app.WindowSelectionCheckBox.Value;
            
        end

        % Value changed function: xDAWNCheckBox
        function xDAWNCheckBoxValueChanged(app, event)
            app.features(2) = app.xDAWNCheckBox.Value;
            
        end

        % Value changed function: OverlapAvgCheckBox
        function OverlapAvgCheckBoxValueChanged(app, event)
            app.features(3) = app.OverlapAvgCheckBox.Value;
            
        end

        % Value changed function: ConsecAvgCheckBox
        function ConsecAvgCheckBoxValueChanged(app, event)
            app.features(4) = app.ConsecAvgCheckBox.Value;
            
        end

        % Value changed function: FczCzCheckBox
        function FczCzCheckBoxValueChanged(app, event)
            app.features(5) = app.FczCzCheckBox.Value;
            
        end

        % Value changed function: PCACheckBox
        function PCACheckBoxValueChanged(app, event)
          if app.PCACheckBox.Value==1
              app.featselectmethod = 2;
              app.RsquareCheckBox.Value=0;
          else
              app.featselectmethod = 0;
          end
        end

        % Value changed function: PeakAvgCheckBox
        function PeakAvgCheckBoxValueChanged(app, event)
            app.features(8) = app.PeakAvgCheckBox.Value;
            
        end

        % Value changed function: TimePointsCheckBox
        function TimePointsCheckBoxValueChanged(app, event)
            app.features(7) = app.TimePointsCheckBox.Value;
            
        end

        % Value changed function: RsquareCheckBox
        function RsquareCheckBoxValueChanged(app, event)
               if app.RsquareCheckBox.Value==1
              app.featselectmethod = 1;
              app.PCACheckBox.Value=0;
              else
              app.featselectmethod = 0;
              end
        end

        % Value changed function: AugmentationCheckBox
        function AugmentationCheckBoxValueChanged(app, event)
            app.augmentation = app.AugmentationCheckBox.Value;
            
        end

        % Value changed function: BayesCheckBox
        function BayesCheckBoxValueChanged(app, event)
            app.classifiers(1) = app.BayesCheckBox.Value;
            
        end

        % Value changed function: GNBCheckBox
        function GNBCheckBoxValueChanged(app, event)
            app.classifiers(2) = app.GNBCheckBox.Value;
            
        end

        % Value changed function: LDACheckBox
        function LDACheckBoxValueChanged(app, event)
            app.classifiers(3) = app.LDACheckBox.Value;
            
        end

        % Value changed function: sLDACheckBox
        function sLDACheckBoxValueChanged(app, event)
            app.classifiers(4) = app.sLDACheckBox.Value;
            
        end

        % Value changed function: SVMCheckBox
        function SVMCheckBoxValueChanged(app, event)
            app.classifiers(5) = app.SVMCheckBox.Value;
            
        end

        % Value changed function: SVMrbfCheckBox
        function SVMrbfCheckBoxValueChanged(app, event)
            app.classifiers(6) = app.SVMrbfCheckBox.Value;
            
        end

        % Value changed function: AutoregressiveCheckBox
        function AutoregressiveCheckBoxValueChanged(app, event)
            app.features(9) = app.AutoregressiveCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ErrPDetectionPipelinesUIFigure = uifigure('Visible', 'off');
            app.ErrPDetectionPipelinesUIFigure.Name = 'ErrP Detection Pipelines';
            app.RunButton = uibutton(app.ErrPDetectionPipelinesUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DatasetDropDownLabel = uilabel(app.ErrPDetectionPipelinesUIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.ErrPDetectionPipelinesUIFigure);
            app.DatasetDropDown.Items = {'LSC Speller', 'BNCI Moving Cursor', 'Kaggle P300 Speller', 'HRI (cursor)', 'HRI (robot)', 'Coadaptation', 'Gaming Agent', 'Gaze Speller', 'Tactile Feedback (V)', 'Tactile Feedback (VT)', 'Virtual Cursor'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'LSC Speller';
            app.DownsamplingCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.DownsamplingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DownsamplingCheckBoxValueChanged, true);
            app.DownsamplingCheckBox.Text = 'Downsampling';
            app.FCBCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.FCBCheckBox.ValueChangedFcn = createCallbackFcn(app, @FCBCheckBoxValueChanged, true);
            app.FCBCheckBox.Text = 'FCB';
            app.xDAWNCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.xDAWNCheckBox.ValueChangedFcn = createCallbackFcn(app, @xDAWNCheckBoxValueChanged, true);
            app.xDAWNCheckBox.Text = 'xDAWN';
            app.OverlapAvgCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.OverlapAvgCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlapAvgCheckBoxValueChanged, true);
            app.OverlapAvgCheckBox.Text = 'Overlap.Avg.';
            app.ConsecAvgCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.ConsecAvgCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsecAvgCheckBoxValueChanged, true);
            app.ConsecAvgCheckBox.Text = 'Consec.Avg.';
            app.FczCzCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.FczCzCheckBox.ValueChangedFcn = createCallbackFcn(app, @FczCzCheckBoxValueChanged, true);
            app.FczCzCheckBox.Text = 'Fcz.Cz';
            app.PeakAvgCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.PeakAvgCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeakAvgCheckBoxValueChanged, true);
            app.PeakAvgCheckBox.Text = 'Peak.Avg.';
            app.TimePointsCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.TimePointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimePointsCheckBoxValueChanged, true);
            app.TimePointsCheckBox.Text = 'TimePoints';
            app.BayesCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.BayesCheckBox.ValueChangedFcn = createCallbackFcn(app, @BayesCheckBoxValueChanged, true);
            app.BayesCheckBox.Text = 'Bayes';
            app.GNBCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.GNBCheckBox.ValueChangedFcn = createCallbackFcn(app, @GNBCheckBoxValueChanged, true);
            app.GNBCheckBox.Text = 'GNB';
            app.LDACheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.LDACheckBox.ValueChangedFcn = createCallbackFcn(app, @LDACheckBoxValueChanged, true);
            app.LDACheckBox.Text = 'LDA';
            app.sLDACheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.sLDACheckBox.ValueChangedFcn = createCallbackFcn(app, @sLDACheckBoxValueChanged, true);
            app.sLDACheckBox.Text = 'sLDA';
            app.SVMCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.SVMCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVMCheckBoxValueChanged, true);
            app.SVMCheckBox.Text = 'SVM';
            app.SVMrbfCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.SVMrbfCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVMrbfCheckBoxValueChanged, true);
            app.SVMrbfCheckBox.Text = 'SVMrbf';
            app.AugmentationCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.AugmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AugmentationCheckBoxValueChanged, true);
            app.AugmentationCheckBox.Text = 'Augmentation';
            app.RsquareCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.RsquareCheckBox.ValueChangedFcn = createCallbackFcn(app, @RsquareCheckBoxValueChanged, true);
            app.RsquareCheckBox.Text = 'Rsquare';
            app.WindowSelectionCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.WindowSelectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @WindowSelectionCheckBoxValueChanged, true);
            app.WindowSelectionCheckBox.Text = 'WindowSelection';
            app.PCACheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.PCACheckBox.ValueChangedFcn = createCallbackFcn(app, @PCACheckBoxValueChanged, true);
            app.PCACheckBox.Text = 'PCA';
            app.AutoregressiveCheckBox = uicheckbox(app.ErrPDetectionPipelinesUIFigure);
            app.AutoregressiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoregressiveCheckBoxValueChanged, true);
            app.AutoregressiveCheckBox.Text = 'Autoregressive';
            % Show the figure after all components are created
            app.ErrPDetectionPipelinesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ErrPDetectionPipelinesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ErrPDetectionPipelinesUIFigure)
        end
    end
end